% Plots the trajectory and max height of an object given its initial hieght, intial velocity, and angle of launch. 

classdef ProjectileMotion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TotalDistance                 matlab.ui.control.EditField
        HorizontalDistanceMaxHieghtLabel_2  matlab.ui.control.Label
        HorizontalDistanceMaxHieghtLabel  matlab.ui.control.Label
        HorizontalDistance            matlab.ui.control.EditField
        MaxHeight                     matlab.ui.control.EditField
        MaxHeightftEditFieldLabel     matlab.ui.control.Label
        ResultsLabel                  matlab.ui.control.Label
        InputsLabel                   matlab.ui.control.Label
        IntialVelocity                matlab.ui.control.NumericEditField
        IntialVelocityftsLabel        matlab.ui.control.Label
        InitalAngleDeg                matlab.ui.control.NumericEditField
        InitalAngleDegEditFieldLabel  matlab.ui.control.Label
        IntialHeight                  matlab.ui.control.NumericEditField
        IntialHeightftLabel           matlab.ui.control.Label
        PlotButton                    matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % Inputs
            h0 = app.IntialHeight.Value;
            v0 = app.IntialVelocity.Value;
            theta0 = app.InitalAngleDeg.Value;
            
            % Constants
            g = 32.174; % gravity
            interval = 0.005; % sampling interval
            
            % convert angle to rad
            theta0_rad = theta0*pi/180;
            
            % calculate time in air and create time vector
            t_total = (v0*sin(theta0_rad) + sqrt((v0*sin(theta0_rad))^2 + 2*g*h0))/g;
            t = 0:interval:t_total;  % 1 ms intervals
            
            % calculate x and y
            x = v0*cos(theta0_rad)*t;
            y = h0 + v0*sin(theta0_rad)*t - 0.5*g*t.^2;
            
            % max height (at time th and horizontal distance xh)
            h_max = max(y);
            th = (v0*sin(theta0_rad))/g;
            xh = v0*cos(theta0_rad)*th;
            xmax = max(x);
            
            % animation
            for k = 1:length(t)
                pause(interval);
                h = plot(app.UIAxes,x(k),y(k),'b.');
                hold(app.UIAxes, 'on' );
            end
            hold(app.UIAxes, 'on' );
            stem(app.UIAxes,xh,h_max,'r');

            % Output Results
            app.MaxHeight.Value = num2str(h_max);
            app.HorizontalDistance.Value = num2str(xh);
            app.TotalDistance.Value = num2str(x(end));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Projectile Motion')
            xlabel(app.UIAxes, 'Distance (ft)')
            ylabel(app.UIAxes, 'Height (ft)')
            zlabel(app.UIAxes, 'Z')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.IntialHeightftLabel = uilabel(app.UIFigure);
            app.IntialHeightftLabel.Text = 'Intial Height (ft)';
            app.IntialHeight = uieditfield(app.UIFigure, 'numeric');
            app.InitalAngleDegEditFieldLabel = uilabel(app.UIFigure);
            app.InitalAngleDegEditFieldLabel.Text = 'Inital Angle (Deg.)';
            app.InitalAngleDeg = uieditfield(app.UIFigure, 'numeric');
            app.IntialVelocityftsLabel = uilabel(app.UIFigure);
            app.IntialVelocityftsLabel.Text = 'Intial Velocity (ft/s)';
            app.IntialVelocity = uieditfield(app.UIFigure, 'numeric');
            app.InputsLabel = uilabel(app.UIFigure);
            app.InputsLabel.Text = 'Inputs';
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results';
            app.MaxHeightftEditFieldLabel = uilabel(app.UIFigure);
            app.MaxHeightftEditFieldLabel.Text = 'Max Height (ft)';
            app.MaxHeight = uieditfield(app.UIFigure, 'text');
            app.HorizontalDistance = uieditfield(app.UIFigure, 'text');
            app.HorizontalDistanceMaxHieghtLabel = uilabel(app.UIFigure);
            app.HorizontalDistanceMaxHieghtLabel.Text = 'Horizontal Distance  @  Max Height (ft)';
            app.HorizontalDistanceMaxHieghtLabel_2 = uilabel(app.UIFigure);
            app.HorizontalDistanceMaxHieghtLabel_2.Text = 'Total Distance (ft)';
            app.TotalDistance = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectileMotion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end