% adds, subtracts, or modulates two diferent signals. 
% User can select signal attributes such as frequency, DC offset, and amplitude

classdef SignalPlotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ListBox                      matlab.ui.control.ListBox
        Frequency_2                  matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel_2  matlab.ui.control.Label
        DC_2                         matlab.ui.control.NumericEditField
        DCLabel_2                    matlab.ui.control.Label
        Amplitude_2                  matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel_2    matlab.ui.control.Label
        Signal2Label                 matlab.ui.control.Label
        NoiseAmplitude_2             matlab.ui.control.Slider
        Signal1Label                 matlab.ui.control.Label
        NoiseAmplitude               matlab.ui.control.Slider
        NoiseAmplitudeSliderLabel    matlab.ui.control.Label
        DC                           matlab.ui.control.NumericEditField
        DCLabel                      matlab.ui.control.Label
        Durations                    matlab.ui.control.NumericEditField
        DurationsEditFieldLabel      matlab.ui.control.Label
        Frequency                    matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel    matlab.ui.control.Label
        Amplitude                    matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel      matlab.ui.control.Label
        PlotButton                   matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            a1 = app.Amplitude.Value; 
            f1 = app.Frequency.Value;
            T1 = app.Durations.Value;
            n1 = app.NoiseAmplitude.Value;
            k1 = app.DC.Value;
            fs1 = 50*f1;
            Ts1 = 1/fs1;
            t1 = 0:Ts1:T1;
            ns1 = n1*randn(1,length(t1));
            x1 = k1 + a1*cos(2*pi*f1*t1) + ns1;

            a2 = app.Amplitude_2.Value; 
            f2 = app.Frequency_2.Value;
            n2 = app.NoiseAmplitude_2.Value;
            k2 = app.DC_2.Value;
            ns2 = n2*randn(1,length(t1));
            x2 = k2 + a2*cos(2*pi*f2*t1) + ns2;

            switch app.ListBox.Value
                case 'Add'
                    x3 = x1+x2;
                case 'Subtract'
                    x3 = x1-x2;
                case 'Modulate'
                    x3 = x1.*x2;
                otherwise
                    x3 = zeros(size(x1));
            end;


            plot(app.UIAxes,t1,x1,'b',t1,x2,'r',t1,x3,'g');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Signal Plot')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.Amplitude = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.Frequency = uieditfield(app.UIFigure, 'numeric');
            app.DurationsEditFieldLabel = uilabel(app.UIFigure);
            app.DurationsEditFieldLabel.Text = 'Duration (s)';
            app.Durations = uieditfield(app.UIFigure, 'numeric');
            app.DCLabel = uilabel(app.UIFigure);
            app.DCLabel.Text = 'DC';
            app.DC = uieditfield(app.UIFigure, 'numeric');
            app.NoiseAmplitudeSliderLabel = uilabel(app.UIFigure);
            app.NoiseAmplitudeSliderLabel.Text = 'Noise  Amplitude';
            app.NoiseAmplitude = uislider(app.UIFigure);
            app.NoiseAmplitude.Limits = [0 5];
            app.Signal1Label = uilabel(app.UIFigure);
            app.Signal1Label.Text = 'Signal 1';
            app.NoiseAmplitude_2 = uislider(app.UIFigure);
            app.NoiseAmplitude_2.Limits = [0 5];
            app.Signal2Label = uilabel(app.UIFigure);
            app.Signal2Label.Text = 'Signal 2';
            app.AmplitudeEditFieldLabel_2 = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel_2.Text = 'Amplitude';
            app.Amplitude_2 = uieditfield(app.UIFigure, 'numeric');
            app.DCLabel_2 = uilabel(app.UIFigure);
            app.DCLabel_2.Text = 'DC';
            app.DC_2 = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyHzEditFieldLabel_2 = uilabel(app.UIFigure);
            app.FrequencyHzEditFieldLabel_2.Text = 'Frequency (Hz)';
            app.Frequency_2 = uieditfield(app.UIFigure, 'numeric');
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Add', 'Subtract', 'Modulate'};
            app.ListBox.Value = 'Add';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalPlotApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end